classdef lab_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PlotPanel                    matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        IndipendentcoordinatesPanel  matlab.ui.container.Panel
        l_3Slider                    matlab.ui.control.Slider
        l_3SliderLabel               matlab.ui.control.Label
        l_2Slider                    matlab.ui.control.Slider
        l_2SliderLabel               matlab.ui.control.Label
        l_1Slider                    matlab.ui.control.Slider
        l_1SliderLabel               matlab.ui.control.Label
        theta_2Slider                matlab.ui.control.Slider
        theta_2SliderLabel           matlab.ui.control.Label
        theta_1Slider                matlab.ui.control.Slider
        theta_1SliderLabel           matlab.ui.control.Label
    end


    methods (Access = private)
        
        function homogeneous(app, theta_1, theta_2, l_1, l_2, l_3)
            T_10 = [cos(theta_1) -sin(theta_2) 0 0;...
                    sin(theta_1) cos(theta_1) 0 0; ...
                    0 0 1 0;...
                    0 0 0 1];
            T_21 = [1 0 0 0;...
                    0 1 0 0;...
                    0 0 1 l_1;
                    0 0 0 1];
            T_32 = [1 0 0 l_2;...
                    0 1 0 0;...
                    0 0 1 0;...
                    0 0 0 1];
            T_43 = [cos(-theta_2) 0 sin(-theta_2) 0;...
                    0 1 0 0;...
                    -sin(-theta_2) 0 cos(-theta_2) 0;...
                    0 0 0 1];

            P_0 = [0 0 0 1]'
            P_1 = T_10*T_21*P_0;
            P_2 = T_10*T_21*T_32*P_0
            P_3 = T_10*T_21*T_32*T_43*[l_3 0 0 1]'

            hold(app.UIAxes, 'off')
            plot3(app.UIAxes,[P_0(1), P_1(1)],[P_0(2), P_1(2)],[P_0(3), P_1(3)])
            hold(app.UIAxes, 'on')
            plot3(app.UIAxes,[P_1(1), P_2(1)],[P_1(2), P_2(2)],[P_1(3), P_2(3)])
            plot3(app.UIAxes,[P_2(1), P_3(1)],[P_2(2), P_3(2)],[P_2(3), P_3(3)])
            axis(app.UIAxes,'equal')
            view(app.UIAxes, 45,30)
            xlim(app.UIAxes, [-2,2])
            ylim(app.UIAxes, [-2,2])
            zlim(app.UIAxes, [-2,2])
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: theta_1Slider
        function theta_1SliderValueChanged(app, event)

            homogeneous(app, app.theta_1Slider.Value, app.theta_2Slider.Value, app.l_1Slider.Value, app.l_2Slider.Value, app.l_3Slider.Value)
        end

        % Value changed function: theta_2Slider
        function theta_2SliderValueChanged(app, event)

            homogeneous(app, app.theta_1Slider.Value, app.theta_2Slider.Value, app.l_1Slider.Value, app.l_2Slider.Value, app.l_3Slider.Value)
        end

        % Value changed function: l_1Slider
        function l_1SliderValueChanged(app, event)
            homogeneous(app, app.theta_1Slider.Value, app.theta_2Slider.Value, app.l_1Slider.Value, app.l_2Slider.Value, app.l_3Slider.Value)
            
        end

        % Value changed function: l_2Slider
        function l_2SliderValueChanged(app, event)
            homogeneous(app, app.theta_1Slider.Value, app.theta_2Slider.Value, app.l_1Slider.Value, app.l_2Slider.Value, app.l_3Slider.Value)
            
        end

        % Value changed function: l_3Slider
        function l_3SliderValueChanged(app, event)
            homogeneous(app, app.theta_1Slider.Value, app.theta_2Slider.Value, app.l_1Slider.Value, app.l_2Slider.Value, app.l_3Slider.Value)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IndipendentcoordinatesPanel = uipanel(app.UIFigure);
            app.IndipendentcoordinatesPanel.Title = 'Indipendent coordinates';
            app.theta_1SliderLabel = uilabel(app.IndipendentcoordinatesPanel);
            app.theta_1SliderLabel.Text = 'theta_1';
            app.theta_1Slider = uislider(app.IndipendentcoordinatesPanel);
            app.theta_1Slider.Limits = [0 3.14159265358979];
            app.theta_1Slider.ValueChangedFcn = createCallbackFcn(app, @theta_1SliderValueChanged, true);
            app.theta_2SliderLabel = uilabel(app.IndipendentcoordinatesPanel);
            app.theta_2SliderLabel.Text = 'theta_2';
            app.theta_2Slider = uislider(app.IndipendentcoordinatesPanel);
            app.theta_2Slider.Limits = [0 1.5707963267949];
            app.theta_2Slider.ValueChangedFcn = createCallbackFcn(app, @theta_2SliderValueChanged, true);
            app.l_1SliderLabel = uilabel(app.IndipendentcoordinatesPanel);
            app.l_1SliderLabel.Text = 'l_1';
            app.l_1Slider = uislider(app.IndipendentcoordinatesPanel);
            app.l_1Slider.Limits = [0 3];
            app.l_1Slider.ValueChangedFcn = createCallbackFcn(app, @l_1SliderValueChanged, true);
            app.l_1Slider.Value = 1;
            app.l_2SliderLabel = uilabel(app.IndipendentcoordinatesPanel);
            app.l_2SliderLabel.Text = 'l_2';
            app.l_2Slider = uislider(app.IndipendentcoordinatesPanel);
            app.l_2Slider.Limits = [0 2];
            app.l_2Slider.ValueChangedFcn = createCallbackFcn(app, @l_2SliderValueChanged, true);
            app.l_2Slider.Value = 0.5;
            app.l_3SliderLabel = uilabel(app.IndipendentcoordinatesPanel);
            app.l_3SliderLabel.Text = 'l_3';
            app.l_3Slider = uislider(app.IndipendentcoordinatesPanel);
            app.l_3Slider.Limits = [0 3];
            app.l_3Slider.ValueChangedFcn = createCallbackFcn(app, @l_3SliderValueChanged, true);
            app.l_3Slider.Value = 0.6;
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end