classdef lab_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ManipulatorviewPanel       matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        IndipendentvariablesPanel  matlab.ui.container.Panel
        l_1mSlider                 matlab.ui.control.Slider
        l_1Label                   matlab.ui.control.Label
        theta_2radSlider           matlab.ui.control.Slider
        theta_2Label               matlab.ui.control.Label
        theta_1radSlider           matlab.ui.control.Slider
        theta_1Label               matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function transformation(app, theta_1, theta_2, l_1)
            h_0 = 2;
            h_1 = 3;
            h_11 = 2;
            h_2 = 4;

            H_10 = [...
                    cos(theta_1) -sin(theta_1) 0 0;...
                    sin(theta_1) cos(theta_1) 0 0; ...
                    0 0 1 h_0;...
                    0 0 0 1;...
                    ];
           H_21_ = [...
                    1 0 0 0;...
                    0 1 0 0;...
                    0 0 1 h_11;...
                    0 0 0 1;...
                    ];
            H_21 = [...
                    1 0 0 0;...
                    0 cos(theta_2) -sin(theta_2) h_1;...
                    0 sin(theta_2) cos(theta_2) 0;...
                    0 0 0 1;...
                    ];
            H_32 = [...
                    1 0 0 0;...
                    0 1 0 h_2;...
                    0 0 1 0;...
                    0 0 0 1;...
                    ];
            H_43 = [...
                    1 0 0 0;...
                    0 1 0 l_1;...
                    0 0 1 0;...
                    0 0 0 1;...
                    ];  

            hold(app.UIAxes, 'off')
            plot3(app.UIAxes,[0,0],[0,0],[0,0])
            hold(app.UIAxes, 'on')
            grid(app.UIAxes, 'on');
            plotReferenceSystem(app.UIAxes, [0, 0, 0], [1,0,0], [0,1,0], [0,0,1]);

            P_0 = [0, 0, 0, 1]';

            [P_1, xyz_1] = transformReferenceSystem(H_10);
            plotReferenceSystem(app.UIAxes, P_1, xyz_1(:,1), xyz_1(:,2), xyz_1(:,3),'\theta_1',"z");
            [P_2_, xyz_2_] = transformReferenceSystem(H_10*H_21_);
            % plotReferenceSystem(app.UIAxes, P_2_, xyz_2_(:,1), xyz_2_(:,2), xyz_2_(:,3));
            [P_2, xyz_2] = transformReferenceSystem(H_10*H_21_*H_21);
            plotReferenceSystem(app.UIAxes, P_2, xyz_2(:,1), xyz_2(:,2), xyz_2(:,3),'\theta_2',"x");
            [P_3 xyz_3] = transformReferenceSystem(H_10*H_21_*H_21*H_32);
            plotReferenceSystem(app.UIAxes, P_3, xyz_3(:,1), xyz_3(:,2), xyz_3(:,3));
            [P_4 xyz_4] = transformReferenceSystem(H_10*H_21_*H_21*H_32*H_43);
            plotReferenceSystem(app.UIAxes, P_4, xyz_4(:,1), xyz_4(:,2), xyz_4(:,3));

            plotLink(app.UIAxes, P_0, P_1);
            plotLink(app.UIAxes, P_1, P_2_);
            plotLink(app.UIAxes, P_2_, P_2);
            plotLink(app.UIAxes, P_2, P_3);
            plotLink(app.UIAxes, P_3, P_4,'l_1');

            title(app.UIAxes, '');
            axis(app.UIAxes,'equal')
            bounds = axis(app.UIAxes); 

            margin = 0.5;
            xRange = bounds(2) - bounds(1);
            yRange = bounds(4) - bounds(3);
            zRange = bounds(6) - bounds(5);
            xlim(app.UIAxes, [bounds(1) - margin*xRange, bounds(2) + margin*xRange]);
            ylim(app.UIAxes, [bounds(3) - margin*yRange, bounds(4) + margin*yRange]);
            zlim(app.UIAxes, [bounds(5) - margin*zRange, bounds(6) + margin*zRange]);          
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("robotics_library/")
            transformation(app, app.theta_1radSlider.Value, app.theta_2radSlider.Value, app.l_1mSlider.Value)

        end

        % Value changing function: theta_2radSlider
        function theta_2radSliderValueChanging(app, event)
            transformation(app, app.theta_1radSlider.Value, app.theta_2radSlider.Value, app.l_1mSlider.Value)
        end

        % Value changing function: theta_1radSlider
        function theta_1radSliderValueChanging(app, event)
            transformation(app, app.theta_1radSlider.Value, app.theta_2radSlider.Value, app.l_1mSlider.Value)
            
        end

        % Value changing function: l_1mSlider
        function l_1mSliderValueChanging(app, event)
            transformation(app, app.theta_1radSlider.Value, app.theta_2radSlider.Value, app.l_1mSlider.Value)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IndipendentvariablesPanel = uipanel(app.UIFigure);
            app.IndipendentvariablesPanel.Title = 'Indipendent variables';
            app.theta_1Label = uilabel(app.IndipendentvariablesPanel);
            app.theta_1Label.Interpreter = 'latex';
            app.theta_1Label.Text = '$\theta_1$ [rad]';
            app.theta_1radSlider = uislider(app.IndipendentvariablesPanel);
            app.theta_1radSlider.Limits = [0 1.5];
            app.theta_1radSlider.ValueChangingFcn = createCallbackFcn(app, @theta_1radSliderValueChanging, true);
            app.theta_1radSlider.Value = 0.5;
            app.theta_2Label = uilabel(app.IndipendentvariablesPanel);
            app.theta_2Label.Interpreter = 'latex';
            app.theta_2Label.Text = '$\theta_2$ [rad]';
            app.theta_2radSlider = uislider(app.IndipendentvariablesPanel);
            app.theta_2radSlider.Limits = [0 3];
            app.theta_2radSlider.ValueChangingFcn = createCallbackFcn(app, @theta_2radSliderValueChanging, true);
            app.theta_2radSlider.Value = 1;
            app.l_1Label = uilabel(app.IndipendentvariablesPanel);
            app.l_1Label.Interpreter = 'latex';
            app.l_1Label.Text = '$l_1$ [m]';
            app.l_1mSlider = uislider(app.IndipendentvariablesPanel);
            app.l_1mSlider.Limits = [1 2];
            app.l_1mSlider.ValueChangingFcn = createCallbackFcn(app, @l_1mSliderValueChanging, true);
            app.l_1mSlider.Value = 1;
            app.ManipulatorviewPanel = uipanel(app.UIFigure);
            app.ManipulatorviewPanel.Title = 'Manipulator view';
            app.UIAxes = uiaxes(app.ManipulatorviewPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end